classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        AveragedofdaysSlider   matlab.ui.control.Slider
        AveragedofdaysLabel    matlab.ui.control.Label
        StateListBox           matlab.ui.control.ListBox
        StateListBoxLabel      matlab.ui.control.Label
        CountryListBox         matlab.ui.control.ListBox
        CountryListBoxLabel    matlab.ui.control.Label
        OptionsButtonGroup     matlab.ui.container.ButtonGroup
        DailyButton            matlab.ui.control.RadioButton
        CumulativeButton       matlab.ui.control.RadioButton
        DatatoplotButtonGroup  matlab.ui.container.ButtonGroup
        BothButton             matlab.ui.control.RadioButton
        DeathsButton           matlab.ui.control.RadioButton
        CasesButton            matlab.ui.control.RadioButton
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        xData
        Cases_Death_Matrix
        CasesVec
        DeathsVec
        AveragesCases
        AveragesDeaths
        AveragingWindow
        Int_title
        tav
        
    end
    
    
    methods (Access = private)
        
        function PlotData(app)
            app.UIAxes.reset;
            ta = int8('Cumulative Number of Cases in ');
            tb = int8('Cumulative Number of Deaths in ');
            tc = int8('Cumulative Number of Cases and Deaths in ');
            td = int8('Daily Number of Cases in ');
            te = int8('Daily Number of Deaths in ');
            tf = int8('Daily Number of Cases and Deaths in ');
            
            if app.CasesButton.Value && app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                b = bar(app.UIAxes, app.xData, app.AveragesCases, 'b');
                num_title = [ta app.Int_title app.tav];
                title(app.UIAxes, char(num_title), "HorizontalAlignment","center");
            elseif app.CasesButton.Value && app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                bar(app.UIAxes, app.xData, app.AveragesCases, 'b');
                num_title = [td app.Int_title app.tav];
                title(app.UIAxes, char(num_title), "HorizontalAlignment","center");
            elseif app.DeathsButton.Value && app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                plot(app.UIAxes, app.xData, app.AveragesDeaths, 'r');
                num_title = [tb app.Int_title app.tav];
                title(app.UIAxes, char(num_title), "HorizontalAlignment","center");
            elseif app.DeathsButton.Value && app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                plot(app.UIAxes, app.xData, app.AveragesDeaths, 'r');
                num_title = [te app.Int_title app.tav];
                title(app.UIAxes, char(num_title), "HorizontalAlignment","center");
            elseif app.BothButton.Value && app.CumulativeButton.Value
                app.CumulativeVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.xData, app.AveragesCases, 'b'); app.UIAxes.YColor = [0.00 0.00 1.00];
                app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
                set(app.UIAxes, 'YTickLabel', num2str(get(app.UIAxes, 'YTick').' , '%.f'));
                hold(app.UIAxes, 'on');
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, app.xData, app.AveragesDeaths, 'r'); app.UIAxes.YColor = [1.00, 0.00 0.00];
                num_title = [tc app.Int_title app.tav];
                title(app.UIAxes, char(num_title), "HorizontalAlignment","center");
            elseif app.BothButton.Value && app.DailyButton.Value
                app.DailyVec;
                app.ComputeAverages;
                yyaxis(app.UIAxes, 'left');
                bar(app.UIAxes, app.xData, app.AveragesCases, 'b'); app.UIAxes.YColor = [0.00 0.00 1.00];
                app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
                set(app.UIAxes, 'YTickLabel', num2str (get(app.UIAxes, 'YTick').' , '%.f'));
                hold(app.UIAxes, 'on');
                yyaxis(app.UIAxes, 'right');
                plot(app.UIAxes, app.xData, app.AveragesDeaths, 'r'); app.UIAxes.YColor = [1.00, 0.00 0.00];
                num_title = [tf app.Int_title app.tav];
                title(app.UIAxes, char(num_title), "HorizontalAlignment","center");              
                
            end
            
            xlim(app.UIAxes, [app.xData(1), app.xData(end)]); xlim(app.UIAxes, 'manual');
            app.UIAxes.YLimMode = 'auto'; app.UIAxes.YTickMode = 'auto';
            grid(app.UIAxes, 'on');
            datetick(app.UIAxes, 'x', 'mm/dd', 'keeplimits');
            set(app.UIAxes, 'YTickLabel', num2str(get(app.UIAxes, 'YTick').' , '%.f'));
            hold(app.UIAxes, 'off');            
        end
        
        
        function ComputeAverages(app)
            app.AveragesCases = movmean(app.CasesVec, app.AveragingWindow);
            app.AveragesDeaths = movmean(app.DeathsVec, app.AveragingWindow);            
        end
        
        
        function DailyVec(app)
            app.CumulativeVec;
            x = app.CasesVec - circshift(app.CasesVec, 1);
            x(1) = app.CasesVec(1);
            k = logical(x < 0);
            x(k) = 0;
            app.CasesVec = x;
            y = app.DeathsVec - circshift(app.DeathsVec, 1);
            y(1) = app.DeathsVec(1);
            g = logical(y < 0);
            y(g) = 0;
            app.DeathsVec = y;            
        end
        
        
        function CumulativeVec(app)
            Item_Selected = Data(app.CountryListBox.Value);
            [~, in2] = ismember(app.StateListBox.Value, app.StateListBox.Items);
            [~, in1] = ismember(app.CountryListBox.Value, app.CountryListBox.Items);
            if in1 == 1
                app.Cases_Death_Matrix = Item_Selected.global_Cases_And_Deaths;
                app.CasesVec = app.Cases_Death_Matrix(1, :);
                app.DeathsVec = app.Cases_Death_Matrix(2, :);
            else
                app.CasesVec = Item_Selected.Cases_Vector(Item_Selected.i_State(in2));
                app.DeathsVec = Item_Selected.Deaths_Vector(Item_Selected.i_State(in2));
                
            end
        end       
            
        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            globalData = Data('Global');
            app.CountryListBox.Items = (unique(globalData.Country, 'stable'));
            app.StateListBox.Items = (unique(globalData.State, 'stable'));
            app.Cases_Death_Matrix = globalData.global_Cases_And_Deaths;
            app.CasesVec = app.Cases_Death_Matrix(1, :);
            app.DeathsVec = app.Cases_Death_Matrix(2, :);
            startDate = datenum(globalData.Date{1});
            endDate = datenum(globalData.Date{end});
            app.xData = linspace(startDate, endDate, length(globalData.Date));
            app.AveragingWindow = app.AveragedofdaysSlider.Value;
            app.StateListBoxValueChanged;
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            Item_Selected = Data(app.CountryListBox.Value);
            app.StateListBox.Items = (unique(Item_Selected.State, 'stable'));
            app.StateListBoxValueChanged;            
        end

        % Value changed function: StateListBox
        function StateListBoxValueChanged(app, event)
            [~, in2] = ismember(app.StateListBox.Value, app.StateListBox.Items);
            if in2 == 1
                app.Int_title = app.CountryListBox.Value;
            else
                app.Int_title = app.StateListBox.Value;
            end
            app.PlotData;            
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.PlotData;            
        end

        % Selection changed function: OptionsButtonGroup
        function OptionsButtonGroupSelectionChanged(app, event)
            app.PlotData;
            
        end

        % Value changing function: AveragedofdaysSlider
        function AveragedofdaysSliderValueChanging(app, event)
            app.AveragingWindow = event.Value;
            if app.AveragingWindow ~= 1
                tav1 = int8(' ('); tav2 = int8('-day mean)');
                app.tav = [tav1, int8(char(string(round(app.AveragingWindow)))), tav2];
            else
                app.tav = int8('');
            end
            app.PlotData;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.OptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionsButtonGroupSelectionChanged, true);
            app.OptionsButtonGroup.Title = 'Options';
            app.CumulativeButton = uiradiobutton(app.OptionsButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionsButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.StateListBoxLabel = uilabel(app.UIFigure);
            app.StateListBoxLabel.Text = 'State';
            app.StateListBox = uilistbox(app.UIFigure);
            app.StateListBox.ValueChangedFcn = createCallbackFcn(app, @StateListBoxValueChanged, true);
            app.AveragedofdaysLabel = uilabel(app.UIFigure);
            app.AveragedofdaysLabel.Text = {'Averaged'; '# of days'};
            app.AveragedofdaysSlider = uislider(app.UIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragedofdaysSliderValueChanging, true);
            app.AveragedofdaysSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AveragedofdaysSlider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end